classdef conexionv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        registrarTab               matlab.ui.container.Tab
        edadEditField              matlab.ui.control.NumericEditField
        edadEditFieldLabel         matlab.ui.control.Label
        RegistrarmeButton          matlab.ui.control.Button
        contraseaEditField_2       matlab.ui.control.EditField
        contraseaEditField_2Label  matlab.ui.control.Label
        usuarioEditField_2         matlab.ui.control.EditField
        usuarioEditField_2Label    matlab.ui.control.Label
        RespuestaTextArea_2        matlab.ui.control.TextArea
        RespuestaTextArea_2Label   matlab.ui.control.Label
        RegistroLabel              matlab.ui.control.Label
        loginTab                   matlab.ui.container.Tab
        RespuestaTextArea          matlab.ui.control.TextArea
        RespuestaTextAreaLabel     matlab.ui.control.Label
        contraseaEditField         matlab.ui.control.EditField
        contraseaEditFieldLabel    matlab.ui.control.Label
        usuarioEditField           matlab.ui.control.EditField
        usuarioEditFieldLabel      matlab.ui.control.Label
        IniciarsesinLabel          matlab.ui.control.Label
        ConfirmarButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % url = 'http://localhost:8080/consulta';
            % data = webwrite(url,42);
            % disp(data)
            % !node mat.js
        end

        % Button pushed function: ConfirmarButton
        function ConfirmarButtonPushed(app, event)
            %%creamos un objeto json llamado consulta que es el que
            %%enviaremos a la url
            consulta.usuario=app.usuarioEditField.Value;
            consulta.pass=app.contraseaEditField.Value;
            data = jsonencode(consulta,PrettyPrint=true);
            %% guardamos la url en una variable
            url = 'http://localhost:8080/login';
            options = weboptions("MediaType","application/json");
            respuesta = webwrite(url,data,options);
            %% imprimimos el resultado en un textarea
            app.RespuestaTextArea.Value = respuesta;            
        end

        % Button pushed function: RegistrarmeButton
        function RegistrarmeButtonPushed(app, event)
            %%creamos un objeto json llamado consulta que es el que
            %%enviaremos a la url
            consulta.usuario= app.usuarioEditField_2.Value;
            consulta.pass= app.contraseaEditField_2.Value;
            consulta.edad = app.edadEditField.Value;
            data = jsonencode(consulta);
            disp(data);
            %% guardamos la url en una variable
            url = 'http://localhost:8080/logup';
            options = weboptions("MediaType","application/json");
            respuesta = webwrite(url,data,options);
            %% imprimimos el resultado en un textarea
            app.RespuestaTextArea_2.Value = respuesta;  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.registrarTab = uitab(app.TabGroup);
            app.registrarTab.Title = 'registrar';
            app.RegistroLabel = uilabel(app.registrarTab);
            app.RegistroLabel.Text = 'Registro';
            app.RespuestaTextArea_2Label = uilabel(app.registrarTab);
            app.RespuestaTextArea_2Label.Text = 'Respuesta';
            app.RespuestaTextArea_2 = uitextarea(app.registrarTab);
            app.RespuestaTextArea_2.Editable = 'off';
            app.usuarioEditField_2Label = uilabel(app.registrarTab);
            app.usuarioEditField_2Label.Text = 'usuario';
            app.usuarioEditField_2 = uieditfield(app.registrarTab, 'text');
            app.contraseaEditField_2Label = uilabel(app.registrarTab);
            app.contraseaEditField_2Label.Text = 'contraseña';
            app.contraseaEditField_2 = uieditfield(app.registrarTab, 'text');
            app.RegistrarmeButton = uibutton(app.registrarTab, 'push');
            app.RegistrarmeButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrarmeButtonPushed, true);
            app.RegistrarmeButton.Text = 'Registrarme';
            app.edadEditFieldLabel = uilabel(app.registrarTab);
            app.edadEditFieldLabel.Text = 'edad';
            app.edadEditField = uieditfield(app.registrarTab, 'numeric');
            app.loginTab = uitab(app.TabGroup);
            app.loginTab.Title = 'login';
            app.ConfirmarButton = uibutton(app.loginTab, 'push');
            app.ConfirmarButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarButtonPushed, true);
            app.ConfirmarButton.Text = 'Confirmar';
            app.IniciarsesinLabel = uilabel(app.loginTab);
            app.IniciarsesinLabel.Text = 'Iniciar sesión';
            app.usuarioEditFieldLabel = uilabel(app.loginTab);
            app.usuarioEditFieldLabel.Text = 'usuario';
            app.usuarioEditField = uieditfield(app.loginTab, 'text');
            app.contraseaEditFieldLabel = uilabel(app.loginTab);
            app.contraseaEditFieldLabel.Text = 'contraseña';
            app.contraseaEditField = uieditfield(app.loginTab, 'text');
            app.RespuestaTextAreaLabel = uilabel(app.loginTab);
            app.RespuestaTextAreaLabel.Text = 'Respuesta';
            app.RespuestaTextArea = uitextarea(app.loginTab);
            app.RespuestaTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = conexionv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end